classdef MohrCircleFitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MohrCircleFittingToolUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportdataMenu                 matlab.ui.container.Menu
        SaveImageMenu                  matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        Panel_2                        matlab.ui.container.Panel
        AddButton                      matlab.ui.control.Button
        DelButton                      matlab.ui.control.Button
        CleanButton                    matlab.ui.control.Button
        ResultPanel                    matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function results = update(app)
            % check the data from UITable
            F=app.UITable.Data;
            if ~size(F,1)
               return; 
            end
            
            % plot the circle
            C=[(F(:,2)+F(:,1))/2,zeros(size(F,1),1)];
            R=abs(F(:,2)-F(:,1))/2;
            ax=app.UIAxes;
            ax.cla;
            app.TextArea.Value='';
            ax.NextPlot='add';
            for f=F'
                r=abs(f(2)-f(1))/2;
                c=[(f(2)+f(1))/2,0];
                th=-pi/2:0.01:pi/2;
                x=c(1)+r.*sin(th);
                y=c(2)+r.*cos(th);
                plot(ax,x,y,'r');
            end
            axis(ax,'equal');
            ax.Box='on';
%             ax.XLim=[0,max(F(:))];
%             ax.YLim=[0,max(F(:))-min(F(:))];
            ax.XLim=[0,max(F(:)).*1.1];
            ax.YLim=[0,max(R).*1.8];
            % check if need to fitting
            if size(F,1)>1
                % fitting
                dist=@(t,C)abs(C(:,3)-abs(t(1).*C(:,1)-C(:,2)+t(2))./sqrt(1+t(1).^2));
                options = optimoptions('lsqcurvefit','Display','off');
                [X,R2]=lsqcurvefit(dist,[0.1,0],[C,R],zeros(size(F,1),1));
                % plot line
                lx=0:max(F(:));
                ly=X(1)*lx+X(2);
                plot(ax,lx,ly);
                trif=@(varargin) varargin{ 3 - varargin{ 1 } };
%                 text(ax,max(lx)*0.85,max(ly)*0.8,['τ=σ·tan(',num2str(atan(X(1))/pi*180),')',trif(X(2)>0,'+',''),num2str(X(2))],'FontSize',18);
                % show result
                app.TextArea.Value=['τ=σ·tan(',num2str(atan(X(1))/pi*180),')',trif(X(2)>0,'+',''),num2str(X(2)),newline,'R^2=',num2str(R2),newline,'C=',num2str(X(2)),newline,'φ=',num2str(atan(X(1))/pi*180)];
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % title
            prompt={'σ1 / MPa','σ3 / MPa'};
            title = 'Input';
            dims = [1 35];
            definput = {'20','30'};
            tab = inputdlg(prompt,title,dims,definput);
            % get input
            if size(tab,1)>0
                app.UITable.Data=[app.UITable.Data;str2double(tab{1}),str2double(tab{2})];
                % update axes
            end
            app.update();
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            app.UITable.Data=[];
            global rowIndex
            rowIndex=0;
            app.update();
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            global rowIndex
            rowIndex=indices(1);
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            global rowIndex
            if rowIndex
               app.UITable.Data(rowIndex,:)=[]; 
            end
            rowIndex=0;
            app.update();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            helpdlg({'本软件由中国地质大学 (武汉) 工程学院夏丁开发。';'利用最小二乘法，可方便地拟合应力莫尔圆。';'欢迎大家使用，并向我们提出改进意见！';'联系邮箱: cug_xia@cug.edu.cn.com  夏丁'},'关于');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.update();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MohrCircleFittingToolUIFigure = uifigure;
            app.MohrCircleFittingToolUIFigure.Name = 'MohrCircleFittingTool';
            app.FileMenu = uimenu(app.MohrCircleFittingToolUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.Text = 'Import data';
            app.SaveImageMenu = uimenu(app.FileMenu);
            app.SaveImageMenu.Text = 'Save Image';
            app.AboutMenu = uimenu(app.MohrCircleFittingToolUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.MohrCircleFittingToolUIFigure);
            title(app.UIAxes, 'σ-τ')
            xlabel(app.UIAxes, 'σ/MPa')
            ylabel(app.UIAxes, 'τ/MPa')
            app.Panel = uipanel(app.MohrCircleFittingToolUIFigure);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'σ1'; 'σ3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'add σ1 and σ3.'};
            app.Panel_2 = uipanel(app.Panel);
            app.AddButton = uibutton(app.Panel_2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DelButton = uibutton(app.Panel_2, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.CleanButton = uibutton(app.Panel_2, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.ResultPanel = uipanel(app.MohrCircleFittingToolUIFigure);
            app.ResultPanel.Title = 'Result';
            app.TextArea = uitextarea(app.ResultPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MohrCircleFitting

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MohrCircleFittingToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MohrCircleFittingToolUIFigure)
        end
    end
end